classdef MaskMaker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImageNumberDropDownLabel    matlab.ui.control.Label
        ImageNumberDropDown         matlab.ui.control.DropDown
        AddMasksPanel               matlab.ui.container.Panel
        MaskTypeDropDownLabel       matlab.ui.control.Label
        MaskTypeDropDown            matlab.ui.control.DropDown
        CreateMaskButton            matlab.ui.control.Button
        MaskIterationDropDownLabel  matlab.ui.control.Label
        MaskIterationDropDown       matlab.ui.control.DropDown
        UpperLimitSliderLabel       matlab.ui.control.Label
        UpperLimitSlider            matlab.ui.control.Slider
        CenterFindingPanel          matlab.ui.container.Panel
        YGuessEditFieldLabel        matlab.ui.control.Label
        YGuessEditField             matlab.ui.control.NumericEditField
        XGuessEditFieldLabel        matlab.ui.control.Label
        XGuessEditField             matlab.ui.control.NumericEditField
        FindCenterButton            matlab.ui.control.Button
        LowerLimitSliderLabel       matlab.ui.control.Label
        LowerLimitSlider            matlab.ui.control.Slider
        CAxisLabel                  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        SaveMaskButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Mask = cell(1,1); % Initialize the mask array holder
        roi = images.roi.Circle;
        DataMat
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DataMat)
            % Initialize all the values to the input matrix
            NumImages=size(DataMat,3);
            sz = size(DataMat); sz = sz(1:2);
            IM = imagesc(app.UIAxes,DataMat(:,:,1));
            colorbar(app.UIAxes)
            IM.ButtonDownFcn = @mouseClick;
            cAxisValue = caxis(app.UIAxes);
            ImageNumChoice = cell(1,NumImages);
            for IMi = 1:NumImages
                ImageNumChoice{IMi} = ['Image ' num2str(IMi)];
            end
            app.ImageNumberDropDown.Items = ImageNumChoice;
            app.UpperLimitSlider.Limits = [cAxisValue(1)*1.01+cAxisValue(2)*0.001 cAxisValue(2)]; % The extra values are so there is no overlap even in the case of zero.
            app.UpperLimitSlider.Value = cAxisValue(2);
            app.LowerLimitSlider.Limits = [cAxisValue(1) cAxisValue(2)*0.99];   % This will have overlap if the values are all negative.
            app.LowerLimitSlider.Value = cAxisValue(1);
            app.Mask{1,1} = ones(sz);
            
            function mouseClick(Image, Hit, event)
                switch Hit.EventName
                    case 'Hit'
                        % Properties: Btn/IntersectionPoint/Src/Evnt
                        posData = Hit.IntersectionPoint;
                    case 'MouseData'
                        % Properties: Src/EvntName
                end
                app.XGuessEditField.Value = posData(1,1);
                app.YGuessEditField.Value = posData(1,2);
                %                 xline(app.UIAxes,cp(1,1));
            end
            
            app.DataMat = DataMat;
        end

        % Button pushed function: CreateMaskButton
        function CreateMaskButtonPushed(app, event)
            holder = app.UIAxes.Children(size(app.UIAxes.Children,1)).ButtonDownFcn;
            app.UIAxes.Children(size(app.UIAxes.Children,1)).ButtonDownFcn = [];
            TitleHolder = app.UIAxes.Title.String;
            app.UIAxes.Title.String = 'Double click the RIO to create the mask';
            I = app.UIAxes.Children(size(app.UIAxes.Children,1)).CData;
            app.UIAxes.Children(size(app.UIAxes.Children,1));
            sz=size(I);
            switch app.MaskTypeDropDown.Value
                case 'Circle'
                    app.roi = images.roi.Circle(app.UIAxes,'Center',[sz(1)/2 sz(2)/2],'Radius',sz(1)/4);
                case 'Rectangle'
                    app.roi = images.roi.Rectangle(app.UIAxes,'Position',[sz(1)/4 sz(1)/4 sz(1)/4 sz(1)/4]);
                case 'Draw'
                    app.roi = images.roi.Freehand(app.UIAxes,'Position',[sz(1)/4 sz(2)/4; 2*sz(1)/4 sz(2)/4; 2*sz(1)/4 3*sz(2)/4; sz(1)/4 3*sz(2)/4]);
            end
            wait(app.roi)
            MaskArea = ~createMask(app.roi,I);
            NumMask = size(app.Mask,2);
            MaskIter = app.MaskIterationDropDown.Value;
            MaskIter = str2double(MaskIter(6:end)) + 1;
            app.Mask{1,NumMask+1} = app.Mask{1,MaskIter}.*MaskArea;
            app.UIAxes.Children(size(app.UIAxes.Children,1)).CData = app.UIAxes.Children(size(app.UIAxes.Children,1)).CData.*app.Mask{1,NumMask+1};
            maskValueHolder = cell(1,1);
            for Mi = 1:NumMask + 1
                maskValueHolder{1,Mi} = ['Mask ' num2str(Mi-1)];
            end
            app.MaskIterationDropDown.Items = maskValueHolder;
            app.MaskIterationDropDown.Value = ['Mask ' num2str(NumMask)];
            app.UIAxes.Children(size(app.UIAxes.Children,1)).ButtonDownFcn = holder;
            app.UIAxes.Title.String = TitleHolder;
            delete(app.roi)
        end

        % Value changed function: ImageNumberDropDown
        function ImageNumberDropDownValueChanged(app, event)
            value = app.ImageNumberDropDown.Value;
            value = str2double(value(7:end));
            MaskIter = app.MaskIterationDropDown.Value;
            MaskIter = str2double(MaskIter(6:end))+1;
            app.UIAxes.Children(size(app.UIAxes.Children,1)).CData = app.DataMat(:,:,value).*app.Mask{1,MaskIter};
        end

        % Value changed function: MaskIterationDropDown
        function MaskIterationDropDownValueChanged(app, event)
            value = app.MaskIterationDropDown.Value;
            value = str2double(value(6:end)) + 1;
            ImNum = app.ImageNumberDropDown.Value;
            ImNum = str2double(ImNum(7:end));
            app.UIAxes.Children(size(app.UIAxes.Children,1)).CData = app.DataMat(:,:,ImNum).*app.Mask{1,value};
        end

        % Button pushed function: FindCenterButton
        function FindCenterButtonPushed(app, event)
            ImNum = app.ImageNumberDropDown.Value;
            ImNum = str2double(ImNum(7:end));
            MaskIter = app.MaskIterationDropDown.Value;
            MaskIter = str2double(MaskIter(6:end))+1;
            NaNBlock = app.Mask{1,MaskIter};
            NaNBlock(~NaNBlock) = NaN;
            radiiCenterChecks = [90:10:120 150];
            centerGuess = [app.YGuessEditField.Value app.XGuessEditField.Value];
            center = beamcenter(app.DataMat(:,:,ImNum).*NaNBlock,centerGuess,radiiCenterChecks);
            app.XGuessEditField.Value = center(2);
            app.YGuessEditField.Value = center(1);
        end

        % Value changing function: UpperLimitSlider
        function UpperLimitSliderValueChanging(app, event)
            changingValue = event.Value;
            lowValue = app.LowerLimitSlider.Value;
            caxis(app.UIAxes,[lowValue changingValue]);
            lowLimits = app.LowerLimitSlider.Limits;
            app.LowerLimitSlider.Limits = [lowLimits(1) changingValue*0.99];
        end

        % Value changing function: LowerLimitSlider
        function LowerLimitSliderValueChanging(app, event)
            changingValue = event.Value;
            highValue = app.UpperLimitSlider.Value;
            caxis(app.UIAxes,[changingValue highValue]);
            upperLimits = app.UpperLimitSlider.Limits;
            app.UpperLimitSlider.Limits = [changingValue*1.01 upperLimits(2) ];
        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)
            MaskIter = app.MaskIterationDropDown.Value;
            MaskIter = str2double(MaskIter(6:end)) + 1;
            Mask = app.Mask{1,MaskIter};
            uisave('Mask');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageNumberDropDownLabel = uilabel(app.UIFigure);
            app.ImageNumberDropDownLabel.Text = 'Image Number';
            app.ImageNumberDropDown = uidropdown(app.UIFigure);
            app.ImageNumberDropDown.Items = {'Image 1'};
            app.ImageNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageNumberDropDownValueChanged, true);
            app.ImageNumberDropDown.Value = 'Image 1';
            app.AddMasksPanel = uipanel(app.UIFigure);
            app.AddMasksPanel.Title = 'Add Masks';
            app.MaskTypeDropDownLabel = uilabel(app.AddMasksPanel);
            app.MaskTypeDropDownLabel.Text = 'Mask Type';
            app.MaskTypeDropDown = uidropdown(app.AddMasksPanel);
            app.MaskTypeDropDown.Items = {'Circle', 'Rectangle', 'Draw'};
            app.MaskTypeDropDown.Value = 'Circle';
            app.CreateMaskButton = uibutton(app.AddMasksPanel, 'push');
            app.CreateMaskButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMaskButtonPushed, true);
            app.CreateMaskButton.Text = 'Create Mask';
            app.MaskIterationDropDownLabel = uilabel(app.AddMasksPanel);
            app.MaskIterationDropDownLabel.Text = 'Mask Iteration';
            app.MaskIterationDropDown = uidropdown(app.AddMasksPanel);
            app.MaskIterationDropDown.Items = {'Mask 0'};
            app.MaskIterationDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskIterationDropDownValueChanged, true);
            app.MaskIterationDropDown.Value = 'Mask 0';
            app.UpperLimitSliderLabel = uilabel(app.UIFigure);
            app.UpperLimitSliderLabel.Text = 'Upper Limit';
            app.UpperLimitSlider = uislider(app.UIFigure);
            app.UpperLimitSlider.ValueChangingFcn = createCallbackFcn(app, @UpperLimitSliderValueChanging, true);
            app.UpperLimitSlider.Value = 90;
            app.CenterFindingPanel = uipanel(app.UIFigure);
            app.CenterFindingPanel.Title = 'Center Finding';
            app.YGuessEditFieldLabel = uilabel(app.CenterFindingPanel);
            app.YGuessEditFieldLabel.Text = 'Y Guess';
            app.YGuessEditField = uieditfield(app.CenterFindingPanel, 'numeric');
            app.XGuessEditFieldLabel = uilabel(app.CenterFindingPanel);
            app.XGuessEditFieldLabel.Text = 'X Guess';
            app.XGuessEditField = uieditfield(app.CenterFindingPanel, 'numeric');
            app.FindCenterButton = uibutton(app.CenterFindingPanel, 'push');
            app.FindCenterButton.ButtonPushedFcn = createCallbackFcn(app, @FindCenterButtonPushed, true);
            app.FindCenterButton.Text = 'Find Center';
            app.LowerLimitSliderLabel = uilabel(app.UIFigure);
            app.LowerLimitSliderLabel.Text = 'Lower Limit';
            app.LowerLimitSlider = uislider(app.UIFigure);
            app.LowerLimitSlider.ValueChangingFcn = createCallbackFcn(app, @LowerLimitSliderValueChanging, true);
            app.CAxisLabel = uilabel(app.UIFigure);
            app.CAxisLabel.Text = 'C-Axis';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Click the Image to guess a center')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.SaveMaskButton = uibutton(app.UIFigure, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Save Mask';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaskMaker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end